classdef animator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputanimationfilePanel        matlab.ui.container.Panel
        FilepathTextArea_3              matlab.ui.control.TextArea
        FilepathTextArea_3Label         matlab.ui.control.Label
        SetoutputfileButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnimaltrackdataTab              matlab.ui.container.Tab
        DatafiltersPanel                matlab.ui.container.Panel
        NumberofpointsinselectionTextArea  matlab.ui.control.TextArea
        NumberofpointsinselectionTextAreaLabel  matlab.ui.control.Label
        LatesttimestampinselectionTextArea  matlab.ui.control.TextArea
        LatesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        EarliesttimestampinselectionTextArea  matlab.ui.control.TextArea
        EarliesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        NumberofindividualsselectedTextArea  matlab.ui.control.TextArea
        NumberofindividualsselectedTextAreaLabel  matlab.ui.control.Label
        MaxlongitudeEditField           matlab.ui.control.NumericEditField
        MaxlongitudeEditFieldLabel      matlab.ui.control.Label
        MinlongitudeEditField           matlab.ui.control.NumericEditField
        MinlongitudeEditFieldLabel      matlab.ui.control.Label
        MaxlatitudeEditField            matlab.ui.control.NumericEditField
        MaxlatitudeEditFieldLabel       matlab.ui.control.Label
        MinlatitudeEditField            matlab.ui.control.NumericEditField
        MinlatitudeEditFieldLabel       matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        StartdateDatePickerLabel        matlab.ui.control.Label
        SelectionsummaryLabel           matlab.ui.control.Label
        UpdatefiltersButton             matlab.ui.control.Button
        GeographicextentLabel           matlab.ui.control.Label
        TimerangeLabel                  matlab.ui.control.Label
        IndividualsLabel                matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        AllindividualsNode              matlab.ui.container.TreeNode
        FilepathTextArea                matlab.ui.control.TextArea
        FilepathTextAreaLabel           matlab.ui.control.Label
        SelecttrackdataButton           matlab.ui.control.Button
        TracksvisualizationoptionsTab   matlab.ui.container.Tab
        ShowlegendCheckBox              matlab.ui.control.CheckBox
        GeneratelastframeonlyCheckBox   matlab.ui.control.CheckBox
        MarkersizeSpinner_2             matlab.ui.control.Spinner
        MarkersizeSpinner_2Label        matlab.ui.control.Label
        TrackopacitySpinner             matlab.ui.control.Spinner
        TrackopacitySpinnerLabel        matlab.ui.control.Label
        TrackfrequencyhoursSpinner      matlab.ui.control.Spinner
        TrackfrequencyhoursSpinnerLabel  matlab.ui.control.Label
        ColortracksbyDropDown           matlab.ui.control.DropDown
        ColortracksbyDropDownLabel      matlab.ui.control.Label
        TracklinewidthSpinner           matlab.ui.control.Spinner
        TracklinewidthSpinnerLabel      matlab.ui.control.Label
        MarkerstyleDropDown             matlab.ui.control.DropDown
        MarkerstyleDropDownLabel        matlab.ui.control.Label
        TrackmemoryEditField            matlab.ui.control.NumericEditField
        TrackmemoryEditFieldLabel       matlab.ui.control.Label
        UseblackmarkersCheckBox         matlab.ui.control.CheckBox
        FadetracksCheckBox              matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        ClearcolorsButton               matlab.ui.control.Button
        AddcolorButton                  matlab.ui.control.Button
        VisualizationoptionsPanel       matlab.ui.container.Panel
        UsecustomcolorsCheckBox         matlab.ui.control.CheckBox
        EnvironmentaldataTab            matlab.ui.container.Tab
        LongitudevariableDropDownLabel_2  matlab.ui.control.Label
        LatitudevariableLabel_2         matlab.ui.control.Label
        TimevariableLabel_2             matlab.ui.control.Label
        VariabletoanimateDropDownLabel_2  matlab.ui.control.Label
        ContourlinewidthSpinner         matlab.ui.control.Spinner
        ContourlinewidthSpinnerLabel    matlab.ui.control.Label
        ShowcontourtextCheckBox         matlab.ui.control.CheckBox
        ContourlinecolorDropDown        matlab.ui.control.DropDown
        ContourlinecolorDropDownLabel   matlab.ui.control.Label
        LongitudevariableDropDown_2     matlab.ui.control.DropDown
        LatitudevariableDropDown_2      matlab.ui.control.DropDown
        TimevariableDropDown_2          matlab.ui.control.DropDown
        VariabletoanimateDropDown_2     matlab.ui.control.DropDown
        FilepathTextArea_4              matlab.ui.control.TextArea
        SelectcontourdataButton         matlab.ui.control.Button
        ContourdataLabel                matlab.ui.control.Label
        InvertcolormapCheckBox          matlab.ui.control.CheckBox
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        LongitudevariableDropDown       matlab.ui.control.DropDown
        LongitudevariableDropDownLabel  matlab.ui.control.Label
        LatitudevariableDropDown        matlab.ui.control.DropDown
        LatitudevariableLabel           matlab.ui.control.Label
        TimevariableDropDown            matlab.ui.control.DropDown
        TimevariableLabel               matlab.ui.control.Label
        VariabletoanimateDropDown       matlab.ui.control.DropDown
        VariabletoanimateDropDownLabel  matlab.ui.control.Label
        FilepathTextArea_2              matlab.ui.control.TextArea
        FilepathTextArea_2Label         matlab.ui.control.Label
        SelectgriddeddataButton         matlab.ui.control.Button
        GriddedenvironmentaldataLabel   matlab.ui.control.Label
        ShapefilesTab                   matlab.ui.container.Tab
        FaceopacitySpinner_3            matlab.ui.control.Spinner
        FaceopacitySpinner_3Label       matlab.ui.control.Label
        FaceopacitySpinner_2            matlab.ui.control.Spinner
        FaceopacitySpinner_2Label       matlab.ui.control.Label
        EdgecolorDropDown_3             matlab.ui.control.DropDown
        EdgecolorDropDown_3Label        matlab.ui.control.Label
        FacecolorDropDown_3             matlab.ui.control.DropDown
        FacecolorDropDown_3Label        matlab.ui.control.Label
        LinewidthSpinner_3              matlab.ui.control.Spinner
        LinewidthSpinner_3Label         matlab.ui.control.Label
        LinecolorDropDown_3             matlab.ui.control.DropDown
        LinecolorDropDown_3Label        matlab.ui.control.Label
        FilepathTextArea_2Label_4       matlab.ui.control.Label
        FilepathTextArea_7              matlab.ui.control.TextArea
        SelectshapefileButton_3         matlab.ui.control.Button
        EdgecolorDropDown_2             matlab.ui.control.DropDown
        EdgecolorDropDown_2Label        matlab.ui.control.Label
        FacecolorDropDown_2             matlab.ui.control.DropDown
        FacecolorDropDown_2Label        matlab.ui.control.Label
        LinewidthSpinner_2              matlab.ui.control.Spinner
        LinewidthSpinner_2Label         matlab.ui.control.Label
        LinecolorDropDown_2             matlab.ui.control.DropDown
        LinecolorDropDown_2Label        matlab.ui.control.Label
        FilepathTextArea_2Label_3       matlab.ui.control.Label
        FilepathTextArea_6              matlab.ui.control.TextArea
        SelectshapefileButton_2         matlab.ui.control.Button
        FaceopacitySpinner              matlab.ui.control.Spinner
        FaceopacitySpinnerLabel         matlab.ui.control.Label
        EdgecolorDropDown               matlab.ui.control.DropDown
        EdgecolorDropDownLabel          matlab.ui.control.Label
        FacecolorDropDown               matlab.ui.control.DropDown
        FacecolorDropDownLabel          matlab.ui.control.Label
        LinewidthSpinner                matlab.ui.control.Spinner
        LinewidthSpinnerLabel           matlab.ui.control.Label
        LinecolorDropDown               matlab.ui.control.DropDown
        LinecolorDropDownLabel          matlab.ui.control.Label
        FilepathTextArea_2Label_2       matlab.ui.control.Label
        FilepathTextArea_5              matlab.ui.control.TextArea
        SelectshapefileButton           matlab.ui.control.Button
        LabeledpointsTab                matlab.ui.container.Tab
        MarkersizeSpinner               matlab.ui.control.Spinner
        MarkersizeSpinnerLabel          matlab.ui.control.Label
        MarkercolorDropDown             matlab.ui.control.DropDown
        MarkercolorDropDownLabel        matlab.ui.control.Label
        FilepathTextArea_2Label_5       matlab.ui.control.Label
        FilepathTextArea_8              matlab.ui.control.TextArea
        SelectfileButton                matlab.ui.control.Button
        Labeledpoints                   matlab.ui.control.Label
        ElevationTab                    matlab.ui.container.Tab
        ContourlinecolorDropDown_2      matlab.ui.control.DropDown
        ContourlinecolorDropDown_2Label  matlab.ui.control.Label
        NumberoflevelsSpinner           matlab.ui.control.Spinner
        NumberoflevelsSpinnerLabel      matlab.ui.control.Label
        IncludeelevationcontoursCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        ContourlinewidthSpinner_2       matlab.ui.control.Spinner
        ContourlinewidthSpinner_2Label  matlab.ui.control.Label
        ShowcontourtextCheckBox_2       matlab.ui.control.CheckBox
        SelectdatafilesLabel            matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        CreateanimationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        root
        data_path
        input_path
        input_datafile  % file with input track data
        input_fullpath
        gridded_data
        contour_data
        elevation
        shapefile_stack
        raster_image
        labeled_points
        gridded_datafile
        gridded_path
        gridded_fullpath
        gridded_varnames
        gridded_var_label
        gridded_time_label
        gridded_lat_label
        gridded_long_label
        latmin
        latmax
        lonmin
        lonmax
        start_time
        end_time
        data % input dataset
        filtered_data % subset of the input dataset, filtered by user selections
        individual_id % the individual to plot 
        output_dir
        selected_individuals
        track_memory
        track_colors
    end
    
    methods (Access = private)
        
        function apply_track_filters(app)
            try
                % Select time window 
                app.filtered_data = app.data((app.data.timestamp >= app.start_time) & (app.data.timestamp <= app.end_time), :);
    
                % Select extent 
                app.filtered_data = app.filtered_data((app.filtered_data.location_long>=app.lonmin) & ...
                    (app.filtered_data.location_long <=app.lonmax) & ...
                    (app.filtered_data.location_lat>=app.latmin) & (app.filtered_data.location_lat <= app.latmax),:);
    
                % Select individuals 
                app.filtered_data = select_individuals(app.filtered_data, app.selected_individuals);
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
            
        end
        
        
        function autoselect_valid_varnames(app)
            try
                potential_timevars = {'time', 'timestamp'};
                potential_latvars = {'lat', 'latitude'};
                potential_lonvars = {'lon', 'long', 'longitude'};
    
                matched_vars = {};
    
                matched_timevar = intersect(app.gridded_varnames, potential_timevars);
                if length(matched_timevar) >= 1
                    app.TimevariableDropDown.Value = matched_timevar{1};
                    app.TimevariableDropDownValueChanged()
                    matched_vars(1+length(matched_vars)) = matched_timevar(1);
                end
    
                matched_latvar = intersect(app.gridded_varnames, potential_latvars);
                if length(matched_latvar) >= 1
                    app.LatitudevariableDropDown.Value = matched_latvar{1};
                    app.LatitudevariableDropDownValueChanged()
                    matched_vars(1+length(matched_vars)) = matched_latvar(1);
    
                end
    
                matched_lonvar = intersect(app.gridded_varnames, potential_lonvars);
                if length(matched_lonvar) >= 1
                    app.LongitudevariableDropDown.Value = matched_lonvar{1};
                    app.LongitudevariableDropDownValueChanged()
                    matched_vars(1+length(matched_vars)) = matched_lonvar(1);
    
                end
    
                % Update the var of interest to the 1st var not matching time,
                % lat, or lon
                other_vars = app.gridded_varnames(~ismember(app.gridded_varnames,matched_vars));
                if length(matched_timevar) >= 1
                    app.VariabletoanimateDropDown.Value= other_vars{1};
                    app.VariabletoanimateDropDownValueChanged()
                end
            catch e
                app.StatusTextArea.Value = e.getReport;
            end                
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Initialize inputs for the animation function 
                app.gridded_data = containers.Map();
                app.contour_data = containers.Map();
                app.shapefile_stack = {};
                app.raster_image = containers.Map();
                app.labeled_points = containers.Map();
                app.elevation = containers.Map();
                app.track_colors = [];
            catch e
                app.StatusTextArea.Value = e.getReport;
            end

        end

        % Button pushed function: SelecttrackdataButton
        function SelecttrackdataButtonPushed(app, event)
            try
                % Open file browser to select input data 
                [app.input_datafile, app.input_path] = uigetfile('*.csv', 'select', app.data_path);
                app.input_fullpath = fullfile(app.input_path, app.input_datafile);
                app.FilepathTextArea.Value = app.input_fullpath;
                
                %load the data
                app.data = read_downloaded_data(app.input_fullpath);
                app.filtered_data = app.data;
    
                %update the geolimits 
                [latlim, lonlim] = get_geolimits(app.data, .10);
                app.latmin = latlim(1);
                app.latmax = latlim(2);
                app.lonmin = lonlim(1);
                app.lonmax = lonlim(2);
    
                app.MinlatitudeEditField.Value = app.latmin;
                app.MaxlatitudeEditField.Value = app.latmax;
                app.MinlongitudeEditField.Value = app.lonmin;
                app.MaxlongitudeEditField.Value = app.lonmax;
    
                % set default start and end times 
                app.start_time = min(app.data.timestamp);
                app.end_time = max(app.data.timestamp);
                app.StartdateDatePicker.Value = app.start_time;
                app.EnddateDatePicker.Value = app.end_time;


                % Update the list box with the available individuals 
                individuals = unique(app.data.individual_local_identifier);
    %             app.TrackcolorsListBox.Items = unique(app.data.individual_local_identifier);
    
                % Update tree 
                for i =1:length(individuals)
                    uitreenode(app.AllindividualsNode, "Text", individuals{i});
                end
                expand(app.Tree);
    
                % Add attributes to the color grouping selector
                app.ColortracksbyDropDown.Items = app.data.Properties.VariableNames;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            try
                checkedNodes = app.Tree.CheckedNodes;
                app.selected_individuals = {checkedNodes.Text};
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
            
        end

        % Button pushed function: SelectgriddeddataButton
        function SelectgriddeddataButtonPushed(app, event)
            try
                % Open file browser to select input data 
                [app.gridded_datafile, app.gridded_path] = uigetfile('*.nc', 'select', app.data_path);
                app.gridded_data('filename') = fullfile(app.gridded_path, app.gridded_datafile);
                app.FilepathTextArea_2.Value = app.gridded_data('filename');
                
                % Get variable names 
                app.gridded_varnames= {ncinfo(app.gridded_data('filename')).Variables.Name};
                app.VariabletoanimateDropDown.Items = app.gridded_varnames;
                app.TimevariableDropDown.Items = app.gridded_varnames; 
                app.LatitudevariableDropDown.Items = app.gridded_varnames; 
                app.LongitudevariableDropDown.Items = app.gridded_varnames;
                
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames()
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
 
        end

        % Value changed function: TimevariableDropDown
        function TimevariableDropDownValueChanged(app, event)
            try
                app.gridded_time_label= app.TimevariableDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
            
        end

        % Value changed function: LatitudevariableDropDown
        function LatitudevariableDropDownValueChanged(app, event)
            try
                app.gridded_lat_label = app.LatitudevariableDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end        
        end

        % Value changed function: LongitudevariableDropDown
        function LongitudevariableDropDownValueChanged(app, event)
            try
                app.gridded_long_label = app.LongitudevariableDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: VariabletoanimateDropDown
        function VariabletoanimateDropDownValueChanged(app, event)
            try
                app.gridded_var_label = app.VariabletoanimateDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            try
                value = app.ColormapDropDown.Value;
                app.gridded_data('cmap') = value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end        
        end

        % Value changed function: InvertcolormapCheckBox
        function InvertcolormapCheckBoxValueChanged(app, event)
            try
                value = app.InvertcolormapCheckBox.Value;
                app.gridded_data('invert_cmap') = value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: CreateanimationButton
        function CreateanimationButtonPushed(app, event)
            try
                app.StatusTextArea.Value = "Generating animation... Please be patient";
    
                disp(app.track_colors)
                % tracks colors
                if app.UsecustomcolorsCheckBox.Value && ~isempty(app.track_colors)
                    track_cmap = cmap_from_colors(app.track_colors);
                else
                    track_cmap = lines;
                end

                % Track marker color 
                if app.UseblackmarkersCheckBox.Value
                    track_marker_color = 'k';
                else
                    track_marker_color = NaN;
                end

                % Gather gridded data info 
                if isKey(app.gridded_data, 'filename')
                    app.gridded_data('latvar') = app.LatitudevariableDropDown.Value;
                    app.gridded_data('lonvar') = app.LongitudevariableDropDown.Value;
                    app.gridded_data('timevar') = app.TimevariableDropDown.Value;
                    app.gridded_data('var_of_interest') = app.VariabletoanimateDropDown.Value;
                    app.gridded_data('cmap') = app.ColormapDropDown.Value;
                    app.gridded_data('invert_cmap') = app.InvertcolormapCheckBox.Value;
                end
    
                % Gather contour data info 
                if isKey(app.contour_data, 'filename')
                    app.contour_data('latvar') = app.LatitudevariableDropDown_2.Value;
                    app.contour_data('lonvar') = app.LongitudevariableDropDown_2.Value; 
                    app.contour_data('timevar') = app.TimevariableDropDown_2.Value;
                    app.contour_data('var_of_interest') = app.VariabletoanimateDropDown_2.Value;
                    app.contour_data('LineColor') = app.ContourlinecolorDropDown.Value;
                    app.contour_data('LineWidth') = app.ContourlinewidthSpinner.Value;
                    if app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'on'; 
                    elseif ~app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'off'; end
                end

                % Gather elevation info 
                if app.IncludeelevationcontoursCheckBox.Value
                    app.elevation('LineColor') = app.ContourlinecolorDropDown_2.Value;
                    app.elevation('nlevels') = app.NumberoflevelsSpinner.Value;
                    app.elevation('LineWidth') = app.ContourlinewidthSpinner_2.Value;
                    app.elevation('ShowText') = app.ShowcontourtextCheckBox_2.Value;
                end

                % Gather labeled points info 
                if isKey(app.labeled_points, 'filename')
                    app.labeled_points('marker_color') = app.MarkercolorDropDown.Value;
                    app.labeled_points('marker_size') = app.MarkersizeSpinner.Value;
                end
    
                animate_tracks(app.filtered_data, ...
                    track_cmap=track_cmap, ...
                    track_frequency = hours(app.TrackfrequencyhoursSpinner.Value), ...
                    track_alpha=app.TrackopacitySpinner.Value, ...
                    track_marker_color=track_marker_color, ...
                    track_marker_size=app.MarkersizeSpinner_2.Value,...
                    fade_tracks = app.FadetracksCheckBox.Value,...
                    gridded_data=app.gridded_data, ...
                    contour_data=app.contour_data, ...
                    elevation=app.elevation, ...
                    shapefile_stack=app.shapefile_stack, ...
                    labeled_points=app.labeled_points, ...
                    start_time=dateshift(app.start_time, 'start', 'day'), ...
                    end_time=dateshift(app.end_time, 'start', 'day'), ...
                    track_memory=app.TrackmemoryEditField.Value, ...
                    output_directory=app.output_dir, ...
                    latmin=app.MinlatitudeEditField.Value, latmax=app.MaxlatitudeEditField.Value, ...
                    lonmin=app.MinlongitudeEditField.Value, lonmax=app.MaxlongitudeEditField.Value, ...
                    group_by=app.ColortracksbyDropDown.Value, marker_style=app.MarkerstyleDropDown.Value, ...
                    track_width=app.TracklinewidthSpinner.Value, ...
                    last_frame_only=app.GeneratelastframeonlyCheckBox.Value, ...
                    show_legend=app.ShowlegendCheckBox.Value)
    
                app.StatusTextArea.Value = "Animation saved to the output directory.";
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: SetoutputfileButton
        function SetoutputfileButtonPushed(app, event)
            try
                app.output_dir = uigetdir();
                app.FilepathTextArea_3.Value = app.output_dir; 
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            try
                app.start_time = app.StartdateDatePicker.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end            
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            try
                app.end_time = app.EnddateDatePicker.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end            
        end

        % Value changed function: MinlatitudeEditField
        function MinlatitudeEditFieldValueChanged(app, event)
            try
                app.latmin = app.MinlatitudeEditField.Value;
             catch e
                app.StatusTextArea.Value = e.getReport;
            end           
        end

        % Value changed function: MaxlatitudeEditField
        function MaxlatitudeEditFieldValueChanged(app, event)
            try
                app.latmax = app.MaxlatitudeEditField.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end            
        end

        % Value changed function: MinlongitudeEditField
        function MinlongitudeEditFieldValueChanged(app, event)
            try
                app.lonmin = app.MinlongitudeEditField.Value;
             catch e
                app.StatusTextArea.Value = e.getReport;
            end           
        end

        % Value changed function: MaxlongitudeEditField
        function MaxlongitudeEditFieldValueChanged(app, event)
            try
                app.lonmax = app.MaxlongitudeEditField.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end    
        end

        % Value changed function: TrackmemoryEditField
        function TrackmemoryEditFieldValueChanged(app, event)
            try
                app.track_memory = app.TrackmemoryEditField.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end            
        end

        % Button pushed function: UpdatefiltersButton
        function UpdatefiltersButtonPushed(app, event)
            try
                app.apply_track_filters()
                app.NumberofpointsinselectionTextArea.Value = num2str(height(app.filtered_data));
                app.NumberofindividualsselectedTextArea.Value = num2str(length(unique(app.filtered_data.individual_local_identifier)));
                app.EarliesttimestampinselectionTextArea.Value = datestr(min(app.filtered_data.timestamp));
                app.LatesttimestampinselectionTextArea.Value = datestr(max(app.filtered_data.timestamp));
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: SelectcontourdataButton
        function SelectcontourdataButtonPushed(app, event)
            try
                [datafile, path] = uigetfile('*.nc', 'select', app.data_path);
                app.contour_data('filename') = fullfile(path, datafile);
                app.FilepathTextArea_4.Value = app.contour_data('filename');
                
                % Get variable names 
                varnames= {ncinfo(app.contour_data('filename')).Variables.Name};
                app.VariabletoanimateDropDown_2.Items = varnames;
                app.TimevariableDropDown_2.Items = varnames; 
                app.LatitudevariableDropDown_2.Items = varnames; 
                app.LongitudevariableDropDown_2.Items = varnames;
                
                % Auto-select time, lat, and long
    %             app.autoselect_valid_varnames()
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: SelectshapefileButton
        function SelectshapefileButtonPushed(app, event)
            try
                shapefile = containers.Map();
                [datafile, path] = uigetfile('*.shp', 'select', app.data_path);
                shapefile('filename') = fullfile(path, datafile);
                app.FilepathTextArea_5.Value = shapefile('filename'); 
                % Get geometry of shapefile 
                geom = shaperead(shapefile('filename'), 'RecordNumbers', 1).Geometry;
    
                if strcmp(geom, 'Line')
                    app.LinecolorDropDown.Editable=true;
                    app.LinecolorDropDown.Enable=true;
                    app.LinewidthSpinner.Editable=true;
                    app.LinewidthSpinner.Enable=true;
                    app.EdgecolorDropDown.Editable = false;
                    app.EdgecolorDropDown.Enable = false;
                    app.FacecolorDropDown.Editable = false;
                    app.FacecolorDropDown.Enable = false; 
                    app.FaceopacitySpinner.Editable = false;
                    app.FaceopacitySpinner.Enable = false; 
                    shapefile('LineColor') = app.LinecolorDropDown.Value;
                    shapefile('LineWidth') = app.LinewidthSpinner.Value;
                elseif strcmp(geom, 'Polygon')
                    app.EdgecolorDropDown.Editable = true;
                    app.EdgecolorDropDown.Enable = true;
                    app.FacecolorDropDown.Editable = true;
                    app.FacecolorDropDown.Enable = true; 
                    app.FaceopacitySpinner.Editable = true;
                    app.FaceopacitySpinner.Enable = true; 
                    app.LinecolorDropDown.Editable=false;
                    app.LinecolorDropDown.Enable=false;
                    app.LinewidthSpinner.Editable=false;
                    app.LinewidthSpinner.Enable=false;
                    shapefile('EdgeColor') = app.EdgecolorDropDown.Value;
                    shapefile('FaceColor') = app.FacecolorDropDown.Value; 
                    shapefile('FaceAlpha') = app.FaceopacitySpinner.Value; 
                end
                app.shapefile_stack{end+1} = shapefile;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: LinecolorDropDown
        function LinecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{1}('LineColor') = app.LinecolorDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end            
        end

        % Value changed function: LinewidthSpinner
        function LinewidthSpinnerValueChanged(app, event)
            try
                app.shapefile_stack{1}('LineWidth') = app.LinewidthSpinner.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: EdgecolorDropDown
        function EdgecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{1}('EdgeColor') = app.EdgecolorDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end           
        end

        % Value changed function: FacecolorDropDown
        function FacecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{1}('FaceColor') = app.FacecolorDropDown.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end       
        end

        % Value changed function: FaceopacitySpinner
        function FaceopacitySpinnerValueChanged(app, event)
            try
                app.shapefile_stack{1}('FaceAlpha') = app.FaceopacitySpinner.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end            
        end

        % Button pushed function: SelectshapefileButton_2
        function SelectshapefileButton_2Pushed(app, event)
            try
                shapefile = containers.Map();
                [datafile, path] = uigetfile('*.shp', 'select', app.data_path);
                shapefile('filename') = fullfile(path, datafile);
                app.FilepathTextArea_6.Value = shapefile('filename'); 
                % Get geometry of shapefile 
                geom = shaperead(shapefile('filename'), 'RecordNumbers', 1).Geometry;
    
                if strcmp(geom, 'Line')
                    app.LinecolorDropDown_2.Editable=true;
                    app.LinecolorDropDown_2.Enable=true;
                    app.LinewidthSpinner_2.Editable=true;
                    app.LinewidthSpinner_2.Enable=true;
                    app.EdgecolorDropDown_2.Editable = false;
                    app.EdgecolorDropDown_2.Enable = false;
                    app.FacecolorDropDown_2.Editable = false;
                    app.FacecolorDropDown_2.Enable = false; 
                    app.FaceopacitySpinner_2.Editable = false;
                    app.FaceopacitySpinner_2.Enable = false; 
                    shapefile('LineColor') = app.LinecolorDropDown_2.Value;
                    shapefile('LineWidth') = app.LinewidthSpinner_2.Value;
                elseif strcmp(geom, 'Polygon')
                    app.EdgecolorDropDown_2.Editable = true;
                    app.EdgecolorDropDown_2.Enable = true;
                    app.FacecolorDropDown_2.Editable = true;
                    app.FacecolorDropDown_2.Enable = true; 
                    app.FaceopacitySpinner_2.Editable = true;
                    app.FaceopacitySpinner_2.Enable = true; 
                    app.LinecolorDropDown_2.Editable=false;
                    app.LinecolorDropDown_2.Enable=false;
                    app.LinewidthSpinner_2.Editable=false;
                    app.LinewidthSpinner_2.Enable=false;
                    shapefile('EdgeColor') = app.EdgecolorDropDown_2.Value;
                    shapefile('FaceColor') = app.FacecolorDropDown_2.Value; 
                    shapefile('FaceAlpha') = app.FaceopacitySpinner_2.Value; 
                end
                app.shapefile_stack{end+1} = shapefile;       
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: LinecolorDropDown_2
        function LinecolorDropDown_2ValueChanged(app, event)
            try
                app.shapefile_stack{2}('LineColor') = app.LinecolorDropDown_2.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: LinewidthSpinner_2
        function LinewidthSpinner_2ValueChanged(app, event)
            try
                app.shapefile_stack{2}('LineWidth') = app.LinewidthSpinner_2.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end           
        end

        % Value changed function: EdgecolorDropDown_2
        function EdgecolorDropDown_2ValueChanged(app, event)
            try
                app.shapefile_stack{2}('EdgeColor') = app.EdgecolorDropDown_2.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: FacecolorDropDown_2
        function FacecolorDropDown_2ValueChanged(app, event)
            try
                app.shapefile_stack{2}('FaceColor') = app.FacecolorDropDown_2.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: FaceopacitySpinner_2
        function FaceopacitySpinner_2ValueChanged(app, event)
            try
                app.shapefile_stack{2}('FaceAlpha') = app.FaceopacitySpinner_2.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: SelectshapefileButton_3
        function SelectshapefileButton_3Pushed(app, event)
            try
                shapefile = containers.Map();
                [datafile, path] = uigetfile('*.shp', 'select', app.data_path);
                shapefile('filename') = fullfile(path, datafile);
                app.FilepathTextArea_7.Value = shapefile('filename'); 
                % Get geometry of shapefile 
                geom = shaperead(shapefile('filename'), 'RecordNumbers', 1).Geometry;
    
                if strcmp(geom, 'Line')
                    app.LinecolorDropDown_3.Editable=true;
                    app.LinecolorDropDown_3.Enable=true;
                    app.LinewidthSpinner_3.Editable=true;
                    app.LinewidthSpinner_3.Enable=true;
                    app.EdgecolorDropDown_3.Editable = false;
                    app.EdgecolorDropDown_3.Enable = false;
                    app.FacecolorDropDown_3.Editable = false;
                    app.FacecolorDropDown_3.Enable = false; 
                    app.FaceopacitySpinner_3.Editable = false;
                    app.FaceopacitySpinner_3.Enable = false; 
                    shapefile('LineColor') = app.LinecolorDropDown_3.Value;
                    shapefile('LineWidth') = app.LinewidthSpinner_3.Value;
                elseif strcmp(geom, 'Polygon')
                    app.EdgecolorDropDown_3.Editable = true;
                    app.EdgecolorDropDown_3.Enable = true;
                    app.FacecolorDropDown_3.Editable = true;
                    app.FacecolorDropDown_3.Enable = true; 
                    app.FaceopacitySpinner_3.Editable = true;
                    app.FaceopacitySpinner_3.Enable = true; 
                    app.LinecolorDropDown_3.Editable=false;
                    app.LinecolorDropDown_3.Enable=false;
                    app.LinewidthSpinner_3.Editable=false;
                    app.LinewidthSpinner_3.Enable=false;
                    shapefile('EdgeColor') = app.EdgecolorDropDown_3.Value;
                    shapefile('FaceColor') = app.FacecolorDropDown_3.Value; 
                    shapefile('FaceAlpha') = app.FaceopacitySpinner_3.Value; 
                end
                app.shapefile_stack{end+1} = shapefile;    
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: LinecolorDropDown_3
        function LinecolorDropDown_3ValueChanged(app, event)
            try
                app.shapefile_stack{3}('LineColor') = app.LinecolorDropDown_3.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: LinewidthSpinner_3
        function LinewidthSpinner_3ValueChanged(app, event)
            try
                app.shapefile_stack{3}('LineWidth') = app.LinewidthSpinner_3.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: EdgecolorDropDown_3
        function EdgecolorDropDown_3ValueChanged(app, event)
            try
                app.shapefile_stack{3}('EdgeColor') = app.EdgecolorDropDown_3.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: FacecolorDropDown_3
        function FacecolorDropDown_3ValueChanged(app, event)
            try
                app.shapefile_stack{3}('FaceColor') = app.FacecolorDropDown_3.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Value changed function: FaceopacitySpinner_3
        function FaceopacitySpinner_3ValueChanged(app, event)
            try
                app.shapefile_stack{3}('FaceAlpha') = app.FaceopacitySpinner_3.Value;
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            try
                [datafile, path] = uigetfile('*.csv', 'select', app.data_path);
                app.labeled_points('filename') = fullfile(path, datafile);
                app.FilepathTextArea_8.Value = app.labeled_points('filename'); 
            catch e
                app.StatusTextArea.Value = e.getReport;
            end
        end

        % Button pushed function: ClearcolorsButton
        function ClearcolorsButtonPushed(app, event)
            app.track_colors = [];
            app.UITable.Data = [];
        end

        % Button pushed function: AddcolorButton
        function AddcolorButtonPushed(app, event)
            c = uisetcolor;
            if length(c) == 3
                app.track_colors(height(app.track_colors) + 1,:) = c;
                app.UITable.Data = app.track_colors;
            end

            if height(app.track_colors) >= 30
                app.AddcolorButton.Enable='off';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateanimationButton = uibutton(app.UIFigure, 'push');
            app.CreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanimationButtonPushed, true);
            app.CreateanimationButton.Text = 'Create animation';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.SelectdatafilesLabel = uilabel(app.UIFigure);
            app.SelectdatafilesLabel.Text = 'Select data files:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnimaltrackdataTab = uitab(app.TabGroup);
            app.AnimaltrackdataTab.Title = 'Animal track data';
            app.SelecttrackdataButton = uibutton(app.AnimaltrackdataTab, 'push');
            app.SelecttrackdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttrackdataButtonPushed, true);
            app.SelecttrackdataButton.Text = 'Select track data';
            app.FilepathTextAreaLabel = uilabel(app.AnimaltrackdataTab);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea = uitextarea(app.AnimaltrackdataTab);
            app.FilepathTextArea.Editable = 'off';
            app.FilepathTextArea.Enable = 'off';
            app.DatafiltersPanel = uipanel(app.AnimaltrackdataTab);
            app.DatafiltersPanel.Title = 'Data filters:';
            app.Tree = uitree(app.DatafiltersPanel, 'checkbox');
            app.AllindividualsNode = uitreenode(app.Tree);
            app.AllindividualsNode.Text = 'All individuals';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.IndividualsLabel = uilabel(app.DatafiltersPanel);
            app.IndividualsLabel.Text = 'Individuals: ';
            app.TimerangeLabel = uilabel(app.DatafiltersPanel);
            app.TimerangeLabel.Text = 'Time range: ';
            app.GeographicextentLabel = uilabel(app.DatafiltersPanel);
            app.GeographicextentLabel.Text = 'Geographic extent: ';
            app.UpdatefiltersButton = uibutton(app.DatafiltersPanel, 'push');
            app.UpdatefiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefiltersButtonPushed, true);
            app.UpdatefiltersButton.Text = 'Update filters';
            app.SelectionsummaryLabel = uilabel(app.DatafiltersPanel);
            app.SelectionsummaryLabel.Text = 'Selection summary: ';
            app.StartdateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.EnddateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.MinlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlatitudeEditFieldLabel.Text = 'Min latitude';
            app.MinlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MinlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlatitudeEditFieldValueChanged, true);
            app.MaxlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlatitudeEditFieldLabel.Text = 'Max latitude';
            app.MaxlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlatitudeEditFieldValueChanged, true);
            app.MinlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlongitudeEditFieldLabel.Text = 'Min longitude';
            app.MinlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MinlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlongitudeEditFieldValueChanged, true);
            app.MaxlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlongitudeEditFieldLabel.Text = 'Max longitude';
            app.MaxlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlongitudeEditFieldValueChanged, true);
            app.NumberofindividualsselectedTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofindividualsselectedTextAreaLabel.Text = 'Number of individuals selected:';
            app.NumberofindividualsselectedTextArea = uitextarea(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel.Text = 'Earliest timestamp in selection:';
            app.EarliesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel.Text = 'Latest timestamp in selection:';
            app.LatesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel.Text = 'Number of points in selection:';
            app.NumberofpointsinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.TracksvisualizationoptionsTab = uitab(app.TabGroup);
            app.TracksvisualizationoptionsTab.Title = 'Tracks visualization options';
            app.VisualizationoptionsPanel = uipanel(app.TracksvisualizationoptionsTab);
            app.VisualizationoptionsPanel.Title = 'Visualization options:';
            app.UsecustomcolorsCheckBox = uicheckbox(app.VisualizationoptionsPanel);
            app.UsecustomcolorsCheckBox.Text = 'Use custom colors';
            app.AddcolorButton = uibutton(app.TracksvisualizationoptionsTab, 'push');
            app.AddcolorButton.ButtonPushedFcn = createCallbackFcn(app, @AddcolorButtonPushed, true);
            app.AddcolorButton.Text = 'Add color';
            app.ClearcolorsButton = uibutton(app.TracksvisualizationoptionsTab, 'push');
            app.ClearcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcolorsButtonPushed, true);
            app.ClearcolorsButton.Text = 'Clear colors';
            app.UITable = uitable(app.TracksvisualizationoptionsTab);
            app.UITable.ColumnName = {'R'; 'G'; 'B'};
            app.UITable.RowName = {};
            app.FadetracksCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.FadetracksCheckBox.Text = 'Fade tracks';
            app.UseblackmarkersCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.UseblackmarkersCheckBox.Text = 'Use black markers';
            app.TrackmemoryEditFieldLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackmemoryEditFieldLabel.Text = 'Track memory';
            app.TrackmemoryEditField = uieditfield(app.TracksvisualizationoptionsTab, 'numeric');
            app.TrackmemoryEditField.Limits = [1 Inf];
            app.TrackmemoryEditField.ValueChangedFcn = createCallbackFcn(app, @TrackmemoryEditFieldValueChanged, true);
            app.TrackmemoryEditField.Value = 20;
            app.MarkerstyleDropDownLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.MarkerstyleDropDownLabel.Text = 'Marker style';
            app.MarkerstyleDropDown = uidropdown(app.TracksvisualizationoptionsTab);
            app.MarkerstyleDropDown.Items = {'hexagram', 'pentagram', 'square', 'diamond ', 'circle'};
            app.MarkerstyleDropDown.ItemsData = {'hexagram', 'pentagram', 'square', 'diamond ', 'o'};
            app.MarkerstyleDropDown.Value = 'hexagram';
            app.TracklinewidthSpinnerLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TracklinewidthSpinnerLabel.Text = 'Track line width';
            app.TracklinewidthSpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TracklinewidthSpinner.Step = 0.1;
            app.TracklinewidthSpinner.Limits = [0.1 5];
            app.TracklinewidthSpinner.Value = 1;
            app.ColortracksbyDropDownLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.ColortracksbyDropDownLabel.Text = 'Color tracks by:';
            app.ColortracksbyDropDown = uidropdown(app.TracksvisualizationoptionsTab);
            app.ColortracksbyDropDown.Items = {'individual_local_identifier'};
            app.ColortracksbyDropDown.Placeholder = 'Select attribute';
            app.ColortracksbyDropDown.Value = 'individual_local_identifier';
            app.TrackfrequencyhoursSpinnerLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackfrequencyhoursSpinnerLabel.Text = 'Track frequency (hours)';
            app.TrackfrequencyhoursSpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TrackfrequencyhoursSpinner.Limits = [0.1 168];
            app.TrackfrequencyhoursSpinner.Value = 24;
            app.TrackopacitySpinnerLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackopacitySpinnerLabel.Text = 'Track opacity';
            app.TrackopacitySpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TrackopacitySpinner.Step = 0.1;
            app.TrackopacitySpinner.Limits = [0 1];
            app.TrackopacitySpinner.Value = 0.6;
            app.MarkersizeSpinner_2Label = uilabel(app.TracksvisualizationoptionsTab);
            app.MarkersizeSpinner_2Label.Text = 'Marker size';
            app.MarkersizeSpinner_2 = uispinner(app.TracksvisualizationoptionsTab);
            app.MarkersizeSpinner_2.Step = 5;
            app.MarkersizeSpinner_2.Limits = [1 500];
            app.MarkersizeSpinner_2.Value = 150;
            app.GeneratelastframeonlyCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.GeneratelastframeonlyCheckBox.Text = 'Generate last frame only';
            app.ShowlegendCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.ShowlegendCheckBox.Text = 'Show legend';
            app.ShowlegendCheckBox.Value = true;
            app.EnvironmentaldataTab = uitab(app.TabGroup);
            app.EnvironmentaldataTab.Title = 'Environmental data';
            app.GriddedenvironmentaldataLabel = uilabel(app.EnvironmentaldataTab);
            app.GriddedenvironmentaldataLabel.Text = 'Gridded environmental data:';
            app.SelectgriddeddataButton = uibutton(app.EnvironmentaldataTab, 'push');
            app.SelectgriddeddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgriddeddataButtonPushed, true);
            app.SelectgriddeddataButton.Text = 'Select gridded data';
            app.FilepathTextArea_2Label = uilabel(app.EnvironmentaldataTab);
            app.FilepathTextArea_2Label.Text = 'File path';
            app.FilepathTextArea_2 = uitextarea(app.EnvironmentaldataTab);
            app.FilepathTextArea_2.Editable = 'off';
            app.FilepathTextArea_2.Enable = 'off';
            app.VariabletoanimateDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDownLabel.Text = 'Variable to animate:';
            app.VariabletoanimateDropDown = uidropdown(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDown.Items = {};
            app.VariabletoanimateDropDown.ValueChangedFcn = createCallbackFcn(app, @VariabletoanimateDropDownValueChanged, true);
            app.VariabletoanimateDropDown.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown.Value = {};
            app.TimevariableLabel = uilabel(app.EnvironmentaldataTab);
            app.TimevariableLabel.Text = 'Time variable:';
            app.TimevariableDropDown = uidropdown(app.EnvironmentaldataTab);
            app.TimevariableDropDown.Items = {};
            app.TimevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @TimevariableDropDownValueChanged, true);
            app.TimevariableDropDown.Placeholder = 'Select variable';
            app.TimevariableDropDown.Value = {};
            app.LatitudevariableLabel = uilabel(app.EnvironmentaldataTab);
            app.LatitudevariableLabel.Text = 'Latitude variable:';
            app.LatitudevariableDropDown = uidropdown(app.EnvironmentaldataTab);
            app.LatitudevariableDropDown.Items = {};
            app.LatitudevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @LatitudevariableDropDownValueChanged, true);
            app.LatitudevariableDropDown.Placeholder = 'Select variable';
            app.LatitudevariableDropDown.Value = {};
            app.LongitudevariableDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.LongitudevariableDropDownLabel.Text = 'Longitude variable:';
            app.LongitudevariableDropDown = uidropdown(app.EnvironmentaldataTab);
            app.LongitudevariableDropDown.Items = {};
            app.LongitudevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @LongitudevariableDropDownValueChanged, true);
            app.LongitudevariableDropDown.Placeholder = 'Select variable';
            app.LongitudevariableDropDown.Value = {};
            app.ColormapDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown = uidropdown(app.EnvironmentaldataTab);
            app.ColormapDropDown.Items = {'green', 'blue', 'diverging', 'jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'green';
            app.InvertcolormapCheckBox = uicheckbox(app.EnvironmentaldataTab);
            app.InvertcolormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertcolormapCheckBoxValueChanged, true);
            app.InvertcolormapCheckBox.Text = 'Invert color map';
            app.ContourdataLabel = uilabel(app.EnvironmentaldataTab);
            app.ContourdataLabel.Text = 'Contour data:';
            app.SelectcontourdataButton = uibutton(app.EnvironmentaldataTab, 'push');
            app.SelectcontourdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcontourdataButtonPushed, true);
            app.SelectcontourdataButton.Text = 'Select contour data';
            app.FilepathTextArea_4 = uitextarea(app.EnvironmentaldataTab);
            app.FilepathTextArea_4.Editable = 'off';
            app.FilepathTextArea_4.Enable = 'off';
            app.VariabletoanimateDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDown_2.Items = {};
            app.VariabletoanimateDropDown_2.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown_2.Value = {};
            app.TimevariableDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.TimevariableDropDown_2.Items = {};
            app.TimevariableDropDown_2.Placeholder = 'Select variable';
            app.TimevariableDropDown_2.Value = {};
            app.LatitudevariableDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.LatitudevariableDropDown_2.Items = {};
            app.LatitudevariableDropDown_2.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_2.Value = {};
            app.LongitudevariableDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.LongitudevariableDropDown_2.Items = {};
            app.LongitudevariableDropDown_2.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_2.Value = {};
            app.ContourlinecolorDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.ContourlinecolorDropDownLabel.Text = 'Contour line color';
            app.ContourlinecolorDropDown = uidropdown(app.EnvironmentaldataTab);
            app.ContourlinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown.Value = 'black';
            app.ShowcontourtextCheckBox = uicheckbox(app.EnvironmentaldataTab);
            app.ShowcontourtextCheckBox.Text = 'Show contour text';
            app.ContourlinewidthSpinnerLabel = uilabel(app.EnvironmentaldataTab);
            app.ContourlinewidthSpinnerLabel.Text = 'Contour line width';
            app.ContourlinewidthSpinner = uispinner(app.EnvironmentaldataTab);
            app.ContourlinewidthSpinner.Step = 0.1;
            app.ContourlinewidthSpinner.Limits = [0.2 3];
            app.ContourlinewidthSpinner.Value = 0.7;
            app.VariabletoanimateDropDownLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDownLabel_2.Text = 'Variable to animate:';
            app.TimevariableLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.TimevariableLabel_2.Text = 'Time variable:';
            app.LatitudevariableLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.LatitudevariableLabel_2.Text = 'Latitude variable:';
            app.LongitudevariableDropDownLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.LongitudevariableDropDownLabel_2.Text = 'Longitude variable:';
            app.ShapefilesTab = uitab(app.TabGroup);
            app.ShapefilesTab.Title = 'Shapefiles';
            app.SelectshapefileButton = uibutton(app.ShapefilesTab, 'push');
            app.SelectshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectshapefileButtonPushed, true);
            app.SelectshapefileButton.Text = 'Select shapefile';
            app.FilepathTextArea_5 = uitextarea(app.ShapefilesTab);
            app.FilepathTextArea_5.Editable = 'off';
            app.FilepathTextArea_5.Enable = 'off';
            app.FilepathTextArea_2Label_2 = uilabel(app.ShapefilesTab);
            app.FilepathTextArea_2Label_2.Text = 'File path';
            app.LinecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.LinecolorDropDownLabel.Text = 'Line color';
            app.LinecolorDropDown = uidropdown(app.ShapefilesTab);
            app.LinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LinecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDownValueChanged, true);
            app.LinecolorDropDown.Value = 'black';
            app.LinewidthSpinnerLabel = uilabel(app.ShapefilesTab);
            app.LinewidthSpinnerLabel.Enable = 'off';
            app.LinewidthSpinnerLabel.Text = 'Line width';
            app.LinewidthSpinner = uispinner(app.ShapefilesTab);
            app.LinewidthSpinner.Step = 0.1;
            app.LinewidthSpinner.Limits = [0.2 3];
            app.LinewidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LinewidthSpinnerValueChanged, true);
            app.LinewidthSpinner.Editable = 'off';
            app.LinewidthSpinner.Enable = 'off';
            app.LinewidthSpinner.Value = 0.7;
            app.FacecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.FacecolorDropDownLabel.Enable = 'off';
            app.FacecolorDropDownLabel.Text = 'Face color';
            app.FacecolorDropDown = uidropdown(app.ShapefilesTab);
            app.FacecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.FacecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @FacecolorDropDownValueChanged, true);
            app.FacecolorDropDown.Enable = 'off';
            app.FacecolorDropDown.Value = 'black';
            app.EdgecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.EdgecolorDropDownLabel.Enable = 'off';
            app.EdgecolorDropDownLabel.Text = 'Edge color';
            app.EdgecolorDropDown = uidropdown(app.ShapefilesTab);
            app.EdgecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.EdgecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgecolorDropDownValueChanged, true);
            app.EdgecolorDropDown.Enable = 'off';
            app.EdgecolorDropDown.Value = 'black';
            app.FaceopacitySpinnerLabel = uilabel(app.ShapefilesTab);
            app.FaceopacitySpinnerLabel.Enable = 'off';
            app.FaceopacitySpinnerLabel.Text = 'Face opacity';
            app.FaceopacitySpinner = uispinner(app.ShapefilesTab);
            app.FaceopacitySpinner.Step = 0.1;
            app.FaceopacitySpinner.Limits = [0 1];
            app.FaceopacitySpinner.ValueChangedFcn = createCallbackFcn(app, @FaceopacitySpinnerValueChanged, true);
            app.FaceopacitySpinner.Editable = 'off';
            app.FaceopacitySpinner.Enable = 'off';
            app.FaceopacitySpinner.Value = 1;
            app.SelectshapefileButton_2 = uibutton(app.ShapefilesTab, 'push');
            app.SelectshapefileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectshapefileButton_2Pushed, true);
            app.SelectshapefileButton_2.Text = 'Select shapefile';
            app.FilepathTextArea_6 = uitextarea(app.ShapefilesTab);
            app.FilepathTextArea_6.Editable = 'off';
            app.FilepathTextArea_6.Enable = 'off';
            app.FilepathTextArea_2Label_3 = uilabel(app.ShapefilesTab);
            app.FilepathTextArea_2Label_3.Text = 'File path';
            app.LinecolorDropDown_2Label = uilabel(app.ShapefilesTab);
            app.LinecolorDropDown_2Label.Text = 'Line color';
            app.LinecolorDropDown_2 = uidropdown(app.ShapefilesTab);
            app.LinecolorDropDown_2.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LinecolorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDown_2ValueChanged, true);
            app.LinecolorDropDown_2.Value = 'black';
            app.LinewidthSpinner_2Label = uilabel(app.ShapefilesTab);
            app.LinewidthSpinner_2Label.Enable = 'off';
            app.LinewidthSpinner_2Label.Text = 'Line width';
            app.LinewidthSpinner_2 = uispinner(app.ShapefilesTab);
            app.LinewidthSpinner_2.Step = 0.1;
            app.LinewidthSpinner_2.Limits = [0.2 3];
            app.LinewidthSpinner_2.ValueChangedFcn = createCallbackFcn(app, @LinewidthSpinner_2ValueChanged, true);
            app.LinewidthSpinner_2.Editable = 'off';
            app.LinewidthSpinner_2.Enable = 'off';
            app.LinewidthSpinner_2.Value = 0.7;
            app.FacecolorDropDown_2Label = uilabel(app.ShapefilesTab);
            app.FacecolorDropDown_2Label.Enable = 'off';
            app.FacecolorDropDown_2Label.Text = 'Face color';
            app.FacecolorDropDown_2 = uidropdown(app.ShapefilesTab);
            app.FacecolorDropDown_2.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.FacecolorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FacecolorDropDown_2ValueChanged, true);
            app.FacecolorDropDown_2.Enable = 'off';
            app.FacecolorDropDown_2.Value = 'black';
            app.EdgecolorDropDown_2Label = uilabel(app.ShapefilesTab);
            app.EdgecolorDropDown_2Label.Enable = 'off';
            app.EdgecolorDropDown_2Label.Text = 'Edge color';
            app.EdgecolorDropDown_2 = uidropdown(app.ShapefilesTab);
            app.EdgecolorDropDown_2.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.EdgecolorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EdgecolorDropDown_2ValueChanged, true);
            app.EdgecolorDropDown_2.Enable = 'off';
            app.EdgecolorDropDown_2.Value = 'black';
            app.SelectshapefileButton_3 = uibutton(app.ShapefilesTab, 'push');
            app.SelectshapefileButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectshapefileButton_3Pushed, true);
            app.SelectshapefileButton_3.Text = 'Select shapefile';
            app.FilepathTextArea_7 = uitextarea(app.ShapefilesTab);
            app.FilepathTextArea_7.Editable = 'off';
            app.FilepathTextArea_7.Enable = 'off';
            app.FilepathTextArea_2Label_4 = uilabel(app.ShapefilesTab);
            app.FilepathTextArea_2Label_4.Text = 'File path';
            app.LinecolorDropDown_3Label = uilabel(app.ShapefilesTab);
            app.LinecolorDropDown_3Label.Text = 'Line color';
            app.LinecolorDropDown_3 = uidropdown(app.ShapefilesTab);
            app.LinecolorDropDown_3.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LinecolorDropDown_3.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDown_3ValueChanged, true);
            app.LinecolorDropDown_3.Value = 'black';
            app.LinewidthSpinner_3Label = uilabel(app.ShapefilesTab);
            app.LinewidthSpinner_3Label.Enable = 'off';
            app.LinewidthSpinner_3Label.Text = 'Line width';
            app.LinewidthSpinner_3 = uispinner(app.ShapefilesTab);
            app.LinewidthSpinner_3.Step = 0.1;
            app.LinewidthSpinner_3.Limits = [0.2 3];
            app.LinewidthSpinner_3.ValueChangedFcn = createCallbackFcn(app, @LinewidthSpinner_3ValueChanged, true);
            app.LinewidthSpinner_3.Editable = 'off';
            app.LinewidthSpinner_3.Enable = 'off';
            app.LinewidthSpinner_3.Value = 0.7;
            app.FacecolorDropDown_3Label = uilabel(app.ShapefilesTab);
            app.FacecolorDropDown_3Label.Enable = 'off';
            app.FacecolorDropDown_3Label.Text = 'Face color';
            app.FacecolorDropDown_3 = uidropdown(app.ShapefilesTab);
            app.FacecolorDropDown_3.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.FacecolorDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FacecolorDropDown_3ValueChanged, true);
            app.FacecolorDropDown_3.Enable = 'off';
            app.FacecolorDropDown_3.Value = 'black';
            app.EdgecolorDropDown_3Label = uilabel(app.ShapefilesTab);
            app.EdgecolorDropDown_3Label.Enable = 'off';
            app.EdgecolorDropDown_3Label.Text = 'Edge color';
            app.EdgecolorDropDown_3 = uidropdown(app.ShapefilesTab);
            app.EdgecolorDropDown_3.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.EdgecolorDropDown_3.ValueChangedFcn = createCallbackFcn(app, @EdgecolorDropDown_3ValueChanged, true);
            app.EdgecolorDropDown_3.Enable = 'off';
            app.EdgecolorDropDown_3.Value = 'black';
            app.FaceopacitySpinner_2Label = uilabel(app.ShapefilesTab);
            app.FaceopacitySpinner_2Label.Enable = 'off';
            app.FaceopacitySpinner_2Label.Text = 'Face opacity';
            app.FaceopacitySpinner_2 = uispinner(app.ShapefilesTab);
            app.FaceopacitySpinner_2.Step = 0.1;
            app.FaceopacitySpinner_2.Limits = [0 1];
            app.FaceopacitySpinner_2.ValueChangedFcn = createCallbackFcn(app, @FaceopacitySpinner_2ValueChanged, true);
            app.FaceopacitySpinner_2.Editable = 'off';
            app.FaceopacitySpinner_2.Enable = 'off';
            app.FaceopacitySpinner_2.Value = 1;
            app.FaceopacitySpinner_3Label = uilabel(app.ShapefilesTab);
            app.FaceopacitySpinner_3Label.Enable = 'off';
            app.FaceopacitySpinner_3Label.Text = 'Face opacity';
            app.FaceopacitySpinner_3 = uispinner(app.ShapefilesTab);
            app.FaceopacitySpinner_3.Step = 0.1;
            app.FaceopacitySpinner_3.Limits = [0 1];
            app.FaceopacitySpinner_3.ValueChangedFcn = createCallbackFcn(app, @FaceopacitySpinner_3ValueChanged, true);
            app.FaceopacitySpinner_3.Editable = 'off';
            app.FaceopacitySpinner_3.Enable = 'off';
            app.FaceopacitySpinner_3.Value = 1;
            app.LabeledpointsTab = uitab(app.TabGroup);
            app.LabeledpointsTab.Title = 'Labeled points';
            app.Labeledpoints = uilabel(app.LabeledpointsTab);
            app.Labeledpoints.Text = 'Labeled points';
            app.SelectfileButton = uibutton(app.LabeledpointsTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.FilepathTextArea_8 = uitextarea(app.LabeledpointsTab);
            app.FilepathTextArea_8.Editable = 'off';
            app.FilepathTextArea_8.Enable = 'off';
            app.FilepathTextArea_2Label_5 = uilabel(app.LabeledpointsTab);
            app.FilepathTextArea_2Label_5.Text = 'File path';
            app.MarkercolorDropDownLabel = uilabel(app.LabeledpointsTab);
            app.MarkercolorDropDownLabel.Text = 'Marker color';
            app.MarkercolorDropDown = uidropdown(app.LabeledpointsTab);
            app.MarkercolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.MarkercolorDropDown.Value = 'red';
            app.MarkersizeSpinnerLabel = uilabel(app.LabeledpointsTab);
            app.MarkersizeSpinnerLabel.Text = 'Marker size';
            app.MarkersizeSpinner = uispinner(app.LabeledpointsTab);
            app.MarkersizeSpinner.Limits = [1 100];
            app.MarkersizeSpinner.Editable = 'off';
            app.MarkersizeSpinner.Value = 20;
            app.ElevationTab = uitab(app.TabGroup);
            app.ElevationTab.Title = 'Elevation';
            app.ShowcontourtextCheckBox_2 = uicheckbox(app.ElevationTab);
            app.ShowcontourtextCheckBox_2.Text = 'Show contour text';
            app.ContourlinewidthSpinner_2Label = uilabel(app.ElevationTab);
            app.ContourlinewidthSpinner_2Label.Text = 'Contour line width';
            app.ContourlinewidthSpinner_2 = uispinner(app.ElevationTab);
            app.ContourlinewidthSpinner_2.Step = 0.1;
            app.ContourlinewidthSpinner_2.Limits = [0.2 3];
            app.ContourlinewidthSpinner_2.Value = 0.7;
            app.CheckBox = uicheckbox(app.ElevationTab);
            app.IncludeelevationcontoursCheckBox = uicheckbox(app.ElevationTab);
            app.IncludeelevationcontoursCheckBox.Text = 'Include elevation contours';
            app.NumberoflevelsSpinnerLabel = uilabel(app.ElevationTab);
            app.NumberoflevelsSpinnerLabel.Text = 'Number of levels';
            app.NumberoflevelsSpinner = uispinner(app.ElevationTab);
            app.NumberoflevelsSpinner.Limits = [2 30];
            app.NumberoflevelsSpinner.Value = 10;
            app.ContourlinecolorDropDown_2Label = uilabel(app.ElevationTab);
            app.ContourlinecolorDropDown_2Label.Text = 'Contour line color';
            app.ContourlinecolorDropDown_2 = uidropdown(app.ElevationTab);
            app.ContourlinecolorDropDown_2.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown_2.Value = 'black';
            app.OutputanimationfilePanel = uipanel(app.UIFigure);
            app.OutputanimationfilePanel.Title = 'Output animation file:';
            app.SetoutputfileButton = uibutton(app.OutputanimationfilePanel, 'push');
            app.SetoutputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfileButtonPushed, true);
            app.SetoutputfileButton.Text = 'Set output file';
            app.FilepathTextArea_3Label = uilabel(app.OutputanimationfilePanel);
            app.FilepathTextArea_3Label.Text = 'File path';
            app.FilepathTextArea_3 = uitextarea(app.OutputanimationfilePanel);
            app.FilepathTextArea_3.Editable = 'off';
            app.FilepathTextArea_3.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end