classdef gridded_data_animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InvertcolormapCheckBox          matlab.ui.control.CheckBox
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        FramerateEditField              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        TrackmemoryEditField            matlab.ui.control.NumericEditField
        TrackmemoryEditFieldLabel       matlab.ui.control.Label
        MaxlongitudeEditField           matlab.ui.control.NumericEditField
        MaxlongitudeEditFieldLabel      matlab.ui.control.Label
        MinlongitudeEditField           matlab.ui.control.NumericEditField
        MinlongitudeEditFieldLabel      matlab.ui.control.Label
        MaxlatitudeEditField            matlab.ui.control.NumericEditField
        MaxlatitudeEditFieldLabel       matlab.ui.control.Label
        MinlatitudeEditField            matlab.ui.control.NumericEditField
        MinlatitudeEditFieldLabel       matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        StartdateDatePickerLabel        matlab.ui.control.Label
        variabletoanimateDropDown       matlab.ui.control.DropDown
        variabletoanimateDropDownLabel  matlab.ui.control.Label
        longitudevariableDropDown       matlab.ui.control.DropDown
        longitudevariableDropDownLabel  matlab.ui.control.Label
        latitudevariableDropDown        matlab.ui.control.DropDown
        latitudevariableDropDownLabel   matlab.ui.control.Label
        timevariableDropDown            matlab.ui.control.DropDown
        timevariableDropDownLabel       matlab.ui.control.Label
        AnimationoptionsLabel           matlab.ui.control.Label
        GriddeddataLabel                matlab.ui.control.Label
        FilepathTextArea_3              matlab.ui.control.TextArea
        FilepathTextArea_3Label         matlab.ui.control.Label
        SelectgriddeddataButton         matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        AllindividualsNode              matlab.ui.container.TreeNode
        AnimaltrackdataLabel            matlab.ui.control.Label
        DataselectionLabel              matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        FilepathTextArea                matlab.ui.control.TextArea
        FilepathTextAreaLabel           matlab.ui.control.Label
        SetoutputfileButton             matlab.ui.control.Button
        FilepathTextArea_2              matlab.ui.control.TextArea
        FilepathTextArea_2Label         matlab.ui.control.Label
        SelecttrackdataButton           matlab.ui.control.Button
        CreateanimationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        root
        data_path
        input_path
        input_datafile  % file with input track data
        input_fullpath
        gridded_datafile
        gridded_path
        gridded_fullpath
        gridded_var_label
        gridded_time_label
        gridded_lat_label
        gridded_long_label
        latmin
        latmax
        lonmin
        lonmax
        start_time
        end_time
        data % input dataset
        filtered_data % subset of the input dataset, filtered by user selections
        individual_id % the individual to plot 
        output_path
        output_fullpath %output file name
        selected_individuals
        track_memory
        frame_rate
        cmap
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.root = fileparts(fileparts(mfilename('fullpath')));
            functions_path = char(fullfile(app.root, "functions"));
            app.data_path = char(fullfile(app.root, "data/example_datasets"));
            addpath(functions_path)
            addpath(app.data_path)

        end

        % Button pushed function: CreateanimationButton
        function CreateanimationButtonPushed(app, event)
            app.StatusTextArea.Value = "Generating animation... Please be patient";
            animate_gridded_ndvi(app.filtered_data, app.gridded_fullpath, ...
                app.gridded_var_label, start_time=app.start_time, ...
                end_time=app.end_time, track_memory=app.TrackmemoryEditField.Value, ...
                output_file=app.output_fullpath, frame_rate=app.FramerateEditField.Value, ...
                latmin=app.MinlatitudeEditField.Value, latmax = app.MaxlatitudeEditField.Value, ...
                lonmin=app.MinlongitudeEditField.Value, lonmax=app.MaxlongitudeEditField.Value, ...
                cmap=app.ColormapDropDown.Value, invert_cmap=app.InvertcolormapCheckBox.Value)
            app.StatusTextArea.Value = "Animation saved to the output directory.";
        end

        % Button pushed function: SelecttrackdataButton
        function SelecttrackdataButtonPushed(app, event)
            
            % Open file browser to select input data 
            [app.input_datafile, app.input_path] = uigetfile('*.csv', 'select', app.data_path);
            app.input_fullpath = fullfile(app.input_path, app.input_datafile);
            app.FilepathTextArea.Value = app.input_fullpath;
            
            %load the data
            app.data = read_downloaded_data(app.input_datafile);

            %update the geolimits 
            [latlim, lonlim] = get_geolimits(app.data, .10);
            app.latmin = latlim(1);
            app.latmax = latlim(2);
            app.lonmin = lonlim(1);
            app.lonmax = lonlim(2);

            app.MinlatitudeEditField.Value = app.latmin;
            app.MaxlatitudeEditField.Value = app.latmax;
            app.MinlongitudeEditField.Value = app.lonmin;
            app.MaxlongitudeEditField.Value = app.lonmax;


            % Update the list box with the available individuals 
            individuals = unique(app.data.individual_local_identifier);
%             app.ListBox.Items = unique(app.data.individual_local_identifier);

            % Update tree 
            for i =1:length(individuals)
                uitreenode(app.AllindividualsNode, "Text", individuals{i});
            end
            expand(app.Tree);
        end

        % Button pushed function: SetoutputfileButton
        function SetoutputfileButtonPushed(app, event)
            [output_fname, app.output_path] = uiputfile('output.avi', 'Select', fullfile(app.root, 'output', 'output.avi'));
            app.output_fullpath = fullfile(app.output_path, output_fname);
            app.FilepathTextArea_3.Value = app.output_fullpath; 
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
%             app.selected_individuals = {app.Tree.CheckedNodes.Text};
%             app.filtered_data = select_individuals(app.data, app.selected_individuals);
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.selected_individuals = {checkedNodes.Text};
            app.filtered_data = select_individuals(app.data, app.selected_individuals);
            
        end

        % Button pushed function: SelectgriddeddataButton
        function SelectgriddeddataButtonPushed(app, event)
            % Open file browser to select input data 
            [app.gridded_datafile, app.gridded_path] = uigetfile('*.nc', 'select', app.data_path);
            app.gridded_fullpath = fullfile(app.gridded_path, app.gridded_datafile);
            app.FilepathTextArea_2.Value = app.gridded_fullpath;
            
            % Get variable names 
            gridded_varnames= {ncinfo(app.gridded_fullpath).Variables.Name};
            app.variabletoanimateDropDown.Items = gridded_varnames;
            app.timevariableDropDown.Items = gridded_varnames; 
            app.latitudevariableDropDown.Items = gridded_varnames; 
            app.longitudevariableDropDown.Items = gridded_varnames;
            
 
        end

        % Value changed function: variabletoanimateDropDown
        function variabletoanimateDropDownValueChanged(app, event)
            app.gridded_var_label = app.variabletoanimateDropDown.Value;
            
        end

        % Value changed function: timevariableDropDown
        function timevariableDropDownValueChanged(app, event)
            app.gridded_time_label= app.timevariableDropDown.Value;
            nc_time = ncread(app.gridded_fullpath, app.gridded_time_label);
            nctimestamp = datetime(datevec(double(nc_time + datenum('2000-01-01 00:00:00'))));

            % set default start and end times 
            app.start_time = min(nctimestamp);
            app.end_time = max(nctimestamp);
            app.StartdateDatePicker.Value = app.start_time;
            app.EnddateDatePicker.Value = app.end_time;
            
        end

        % Value changed function: latitudevariableDropDown
        function latitudevariableDropDownValueChanged(app, event)
            app.gridded_lat_label = app.latitudevariableDropDown.Value;
            
        end

        % Value changed function: longitudevariableDropDown
        function longitudevariableDropDownValueChanged(app, event)
            app.gridded_long_label = app.longitudevariableDropDown.Value;
            
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            app.start_time = app.StartdateDatePicker.Value;
            
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            app.end_time = app.EnddateDatePicker.Value;
            
        end

        % Value changed function: MinlatitudeEditField
        function MinlatitudeEditFieldValueChanged(app, event)
            app.latmin = app.MinlatitudeEditField.Value;
            
        end

        % Value changed function: MaxlatitudeEditField
        function MaxlatitudeEditFieldValueChanged(app, event)
            app.latmax = app.MaxlatitudeEditField.Value;
            
        end

        % Value changed function: MinlongitudeEditField
        function MinlongitudeEditFieldValueChanged(app, event)
            app.lonmin = app.MinlongitudeEditField.Value;
            
        end

        % Value changed function: MaxlongitudeEditField
        function MaxlongitudeEditFieldValueChanged(app, event)
            app.lonmax = app.MaxlongitudeEditField.Value;
            
        end

        % Value changed function: TrackmemoryEditField
        function TrackmemoryEditFieldValueChanged(app, event)
            app.track_memory = app.TrackmemoryEditField.Value;
            
        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            app.frame_rate = app.FramerateEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateanimationButton = uibutton(app.UIFigure, 'push');
            app.CreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanimationButtonPushed, true);
            app.CreateanimationButton.Text = 'Create animation';
            app.SelecttrackdataButton = uibutton(app.UIFigure, 'push');
            app.SelecttrackdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttrackdataButtonPushed, true);
            app.SelecttrackdataButton.Text = 'Select track data';
            app.FilepathTextArea_2Label = uilabel(app.UIFigure);
            app.FilepathTextArea_2Label.Text = 'File path';
            app.FilepathTextArea_2 = uitextarea(app.UIFigure);
            app.FilepathTextArea_2.Editable = 'off';
            app.FilepathTextArea_2.Enable = 'off';
            app.SetoutputfileButton = uibutton(app.UIFigure, 'push');
            app.SetoutputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfileButtonPushed, true);
            app.SetoutputfileButton.Text = 'Set output file';
            app.FilepathTextAreaLabel = uilabel(app.UIFigure);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea = uitextarea(app.UIFigure);
            app.FilepathTextArea.Editable = 'off';
            app.FilepathTextArea.Enable = 'off';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.DataselectionLabel = uilabel(app.UIFigure);
            app.DataselectionLabel.Text = 'Data selection: ';
            app.AnimaltrackdataLabel = uilabel(app.UIFigure);
            app.AnimaltrackdataLabel.Text = 'Animal track data:';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.AllindividualsNode = uitreenode(app.Tree);
            app.AllindividualsNode.Text = 'All individuals';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelectgriddeddataButton = uibutton(app.UIFigure, 'push');
            app.SelectgriddeddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgriddeddataButtonPushed, true);
            app.SelectgriddeddataButton.Text = 'Select gridded data';
            app.FilepathTextArea_3Label = uilabel(app.UIFigure);
            app.FilepathTextArea_3Label.Text = 'File path';
            app.FilepathTextArea_3 = uitextarea(app.UIFigure);
            app.FilepathTextArea_3.Editable = 'off';
            app.FilepathTextArea_3.Enable = 'off';
            app.GriddeddataLabel = uilabel(app.UIFigure);
            app.GriddeddataLabel.Text = 'Gridded data:';
            app.AnimationoptionsLabel = uilabel(app.UIFigure);
            app.AnimationoptionsLabel.Text = 'Animation options:';
            app.timevariableDropDownLabel = uilabel(app.UIFigure);
            app.timevariableDropDownLabel.Text = 'time variable';
            app.timevariableDropDown = uidropdown(app.UIFigure);
            app.timevariableDropDown.Items = {};
            app.timevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @timevariableDropDownValueChanged, true);
            app.timevariableDropDown.Placeholder = 'Select variable';
            app.timevariableDropDown.Value = {};
            app.latitudevariableDropDownLabel = uilabel(app.UIFigure);
            app.latitudevariableDropDownLabel.Text = 'latitude variable';
            app.latitudevariableDropDown = uidropdown(app.UIFigure);
            app.latitudevariableDropDown.Items = {};
            app.latitudevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @latitudevariableDropDownValueChanged, true);
            app.latitudevariableDropDown.Placeholder = 'Select variable';
            app.latitudevariableDropDown.Value = {};
            app.longitudevariableDropDownLabel = uilabel(app.UIFigure);
            app.longitudevariableDropDownLabel.Text = 'longitude variable';
            app.longitudevariableDropDown = uidropdown(app.UIFigure);
            app.longitudevariableDropDown.Items = {};
            app.longitudevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @longitudevariableDropDownValueChanged, true);
            app.longitudevariableDropDown.Placeholder = 'Select variable';
            app.longitudevariableDropDown.Value = {};
            app.variabletoanimateDropDownLabel = uilabel(app.UIFigure);
            app.variabletoanimateDropDownLabel.Text = 'variable to animate';
            app.variabletoanimateDropDown = uidropdown(app.UIFigure);
            app.variabletoanimateDropDown.Items = {};
            app.variabletoanimateDropDown.ValueChangedFcn = createCallbackFcn(app, @variabletoanimateDropDownValueChanged, true);
            app.variabletoanimateDropDown.Placeholder = 'Select variable';
            app.variabletoanimateDropDown.Value = {};
            app.StartdateDatePickerLabel = uilabel(app.UIFigure);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.UIFigure);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.EnddateDatePickerLabel = uilabel(app.UIFigure);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.UIFigure);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.MinlatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MinlatitudeEditFieldLabel.Text = 'Min latitude';
            app.MinlatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlatitudeEditFieldValueChanged, true);
            app.MaxlatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxlatitudeEditFieldLabel.Text = 'Max latitude';
            app.MaxlatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlatitudeEditFieldValueChanged, true);
            app.MinlongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MinlongitudeEditFieldLabel.Text = 'Min longitude';
            app.MinlongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlongitudeEditFieldValueChanged, true);
            app.MaxlongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxlongitudeEditFieldLabel.Text = 'Max longitude';
            app.MaxlongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlongitudeEditFieldValueChanged, true);
            app.TrackmemoryEditFieldLabel = uilabel(app.UIFigure);
            app.TrackmemoryEditFieldLabel.Text = 'Track memory';
            app.TrackmemoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrackmemoryEditField.Limits = [1 Inf];
            app.TrackmemoryEditField.ValueChangedFcn = createCallbackFcn(app, @TrackmemoryEditFieldValueChanged, true);
            app.TrackmemoryEditField.Value = 20;
            app.FramerateEditFieldLabel = uilabel(app.UIFigure);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramerateEditField.Limits = [0 Inf];
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            app.FramerateEditField.Value = 14;
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'green', 'blue', 'diverging', 'jet'};
            app.ColormapDropDown.Value = 'green';
            app.InvertcolormapCheckBox = uicheckbox(app.UIFigure);
            app.InvertcolormapCheckBox.Text = 'Invert color map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gridded_data_animation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end